classdef TrigCtrlGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        BolusTriggerCtrlUIFigure        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        HumanMetaVisionTriggerBoxDropDown  matlab.ui.control.DropDown
        HumanMetaVisionTriggerBoxLabel  matlab.ui.control.Label
        InjectorTriggerBoxDropDown      matlab.ui.control.DropDown
        InjectorTriggerBoxDropDownLabel  matlab.ui.control.Label
        TrgDurationEditField            matlab.ui.control.NumericEditField
        secondsEditFieldLabel           matlab.ui.control.Label
        TrgNumEditField                 matlab.ui.control.NumericEditField
        Label                           matlab.ui.control.Label
        DurationPhysioMonitorLabel      matlab.ui.control.Label
        NumberAuroraLabel               matlab.ui.control.Label
        sendcustomtriggerButton         matlab.ui.control.Button
        BOLUSButton                     matlab.ui.control.Button
    end

    %% ========== TODOs ==============================================
    % AVOID pausing for user delay! Use timerObjects!
    % catch exception if writing to serial port failes, e.g. if cable
    % disconnected. Issue UI warning!
    % 
    
    % PROPERTIES ==========================================================
    
    properties (Access = public)
        nrisDataTrg = [] % 2-elemnt with time and trigger#: [secondsOfDay, trgNumber]
        bolusPrepDelay = 10; % delay between 1st trigger and actual bolus release in seconds
        bolusTrgNums = [49 50 51];
        bolusTrgDuration = 20; % duration for setting injector-trigger to high...
        % CAVE: if too short injection will be aborted?!
        logPath = fullfile(userpath,'TriggerCtrlGUI','logs');
        logFile
        lsloutlet
    end
    
    properties (Access = public)
        scomObj_HumTrgBox
        scomObj_InjctrTrgBox
        iON  = 'a';
        iOFF = 'b';
        pON  = 'x';
        pOFF = 'y';
        PreferedCOMnamePatt = '.*Serielles USB-Gerät.*'; % regexp to match prefered COM port name
        physioTrgTimer % Description
        bolusAndPhysTrgTimer
    end
    
    properties (Dependent)
        humTrgBox_used
        injctrTrgBox_used
    end
    
    properties (Constant)
        OutTLabel = 'Trigger_out';
        OutTName = 'Trigger';
        SerialPortParam = {9600}; % Additional parameters passed to the serialport() function        
        SCOM_DISCONNECT_STR = '<DISCONNECTED>';
    end
    
    
    % METHODS =============================================================
    
    %% SETTER, GETTER
    methods 
        function set.scomObj_HumTrgBox(app,val)
            try
               app.scomObj_HumTrgBox.delete();                
            catch ME                
            end
            app.scomObj_HumTrgBox = val;
        end
        function set.scomObj_InjctrTrgBox(app,val)
            try
               app.scomObj_InjctrTrgBox.delete();                
            catch ME                
            end
            app.scomObj_InjctrTrgBox = val;
        end
        % .................................................................
        function val = get.humTrgBox_used(app)
            val = ~strcmp(app.HumanMetaVisionTriggerBoxDropDown.Value, app.SCOM_DISCONNECT_STR);
        end
        function val = get.injctrTrgBox_used(app)
            val = ~strcmp(app.InjectorTriggerBoxDropDown.Value, app.SCOM_DISCONNECT_STR);
        end
    end
    
    %% ##################### PUBLIC, STATIC ###############################
    methods (Access = public, Static)
    
        function startTimerAt(tObj,tStart)
            try
                startat(tObj,tStart);
            catch ME
                if strcmpi(ME.identifier,'MATLAB:timer:startdelaynegative')
                    tObj.StartDelay = 0;
                    start(tObj);
                else
                    throwAsCaller(ME);
                end
            end
        end
        
        %% ================================================================

        function [fullnames, comNums] = getCOMports()
            if ispc
                cmd = 'powershell -command "Get-WmiObject Win32_pnpentity  -Filter """Caption LIKE ''%(COM%)%''""" | Select-Object -Property Name"';
                [s,r] = system(cmd);
                if s~=0
                    warning('INVOSstreamer:getCOMports:systemCmdFailed','Failed to execute system command to retrieve com ports.')
                    [fullnames, comNums] = getCOMportsFallback();
                else
                    fullnames = regexp(r,'(?<=\n)[^\n]*\(COM\d+\)(?=\s*\n)','match');
                    comNums = regexp(fullnames,'(?<=\(COM)\d+','match','once');
                end
            else
                % retrieving full com port names for other OS no
                % implemented yet!
                [fullnames, comNums] = getCOMportsFallback();
            end
            fullnames = [{TrigCtrlGUI.SCOM_DISCONNECT_STR} fullnames];
            comNums = [{NaN} comNums];
        end
        
        %% ================================================================
                
        function [fullnames, comNums] = getCOMportsFallback()
            fullnames = serialportlist("all");
            if ~iscell(fullnames)
                fullnames = cellstr(fullnames);
            end
            comNums = regexp(fullnames,'(?<=COM)\d+','match','once');
        end
        
        %% ================================================================
        
        function comName = getMatlabCOMname(sysComPort)
            comName = regexp(sysComPort,'COM\d+','match','once');
            if ~ismember(comName,serialportlist('all'))
                warning('TrigCtrlGUI:serialPort:NameUnknown2Matlab', ...
                    'COM port ''%s'' seems to be unknown to Matlab. Creating SerialPortObject may fail!', ...
                    sysComPort);
            end            
        end
        
        %% ================================================================
        
         function updateDropDown(dropdObj,newList,preferedItem)
            % currentVal = dropdObj.Value;
            % newValIdx = [];
            % % try to maintain previous value:
            % if ~isempty(currentVal)
            %     newValIdx = find(strcmp(newList,currentVal));
            % end
            % % try to set prefered value
            % if isempty(newValIdx) && nargin>2
            %     m = regexp(newList,preferedItem,'once');
            %     newValIdx = find(~cellfun(@isempty,m),1);
            % end
            % % if new value still empty and list not empty, set to 1
            % if isempty(newValIdx) && ~isempty(newList)
            %     newValIdx = 1;
            % end
            dropdObj.Items = newList;
            % if ~isempty(newValIdx)
            %     dropdObj.Value = newList(newValIdx);
            % end
        end
    end
    
    %% ##################### PRIVATE ######################################
    methods (Access = private)
        

        function setNSPdataTrg(app,value)
            % OBSOLETE
            % sets object property with current time and trg value
            value = fix(value);
            t = rem(now,1)*24*60*60;
            app.nrisDataTrg = [app.nrisDataTrg; [t, value]];
        end
        % .................................................................
        
        function sendLSLtrg(app,val)
            app.lsloutlet.push_sample(val);
        end
        % .................................................................
        
        function sendNIRStrg_(app, value)
            sendLSLtrg(app,value)
            % workaround for lsl-induced aurora crash:
%             setNSPdataTrg(app,value);
        end
        % .................................................................
        
        
        function physioTrgTimerInit(app,duration)
            app.physioTrgTimer.UserData.startedAt = datetime;% use current time to get defined duration
            app.physioTrgTimer.UserData.COMlatency = write2com(app,app.pON);
            app.physioTrgTimer.UserData.duration = duration;
            app.startTimerAt(app.physioTrgTimer, datetime+seconds(duration-.5));
        end
        function physioTrgTimerFnc(app,tObj,event)
            stopAt = tObj.UserData.startedAt + seconds(tObj.UserData.duration);
            delay = stopAt-datetime-seconds(tObj.UserData.COMlatency);
            pause(seconds(delay));
            l = write2com(app,app.pOFF);
        end
        
        % .................................................................
        
        
        function bolusAndPhysTrgTimerFnc(app,tObj,event)
            % struct('COMlatency',[],'delay',[],'startedAt',[],'state',[]));
            % warte wenn delay noch nicht erfüllt:
            tExec = tObj.UserData.startedAt + seconds(tObj.UserData.delay);
            tWait = tExec - datetime;
            pause(seconds(tWait));
            
            tObj.UserData.startedAt = datetime; % (event.Data.time);  % <- CAVE nehmen wir die aktuelle Zeit oder die Zeit zu der der Callback in die queue kam?
            switch tObj.UserData.state
                case 'prep'
                    sendNIRStrg_(app,app.bolusTrgNums(1));
                    fprintf('%s (TCG): Bolus will be released in %.1f seconds...\n', ...
                                datestr(now,'yyyy-mm-dd HH:MM:SS'), app.bolusPrepDelay);
                    tObj.UserData.delay = app.bolusPrepDelay;
                case 'release'
                    sendNIRStrg_(app,app.bolusTrgNums(2));
                    lat = write2com(app,[app.iON app.pON]);
                    tObj.UserData.delay = max(0, app.bolusTrgDuration-lat);
                    writeLog(app,'Bolus released.');
                    fprintf('%s (TCG): Bolus released. Trigger will be low in %.1f seconds...\n', ...
                                datestr(now,'yyyy-mm-dd HH:MM:SS'), app.bolusTrgDuration-lat/2);
                case 'low'
                    sendNIRStrg_(app,app.bolusTrgNums(3));
                    lat = write2com(app,[app.iOFF app.pOFF]);
                    writeLog(app,'Bolus trigger low.');
                otherwise, warning('serious bug');
            end
        end
        % .................................................................
        function bolusAndPhysTrgTimerStopFnc(app,tObj,event)
            switch tObj.UserData.state
                case 'prep'
                    tObj.UserData.state = 'release';
                    app.startTimerAt(tObj, tObj.UserData.startedAt + seconds(tObj.UserData.delay - 1));
                case 'release'
                    tObj.UserData.state = 'low';
                    app.startTimerAt(tObj, tObj.UserData.startedAt + seconds(tObj.UserData.delay - 1));
                case 'low'
                otherwise, warning('serious bug');
            end
        end
        
                
        % .................................................................
        
        function updateCOMportDropDown(app,newPortList)
            app.updateDropDown(app.InjectorTriggerBoxDropDown,       newPortList,app.PreferedCOMnamePatt);
            app.updateDropDown(app.HumanMetaVisionTriggerBoxDropDown,newPortList,app.PreferedCOMnamePatt);
        end
    end
    
    %% ##################### PUBLIC  ######################################
    methods (Access = public)
        
        function sendNIRStrg(app, value)
            writeLog(app,'Triggering NIRS.')
            sendNIRStrg_(app,value);
        end
        % .................................................................

        function sendPhysioTrg(app,duration)
            writeLog(app,'Triggering Physio.');
%             sendPhysioTrg_(app,duration);
            app.physioTrgTimerInit(duration);
        end
        % .................................................................

        function sendNIRSandPhysioTrg(app,value,duration)
            writeLog(app,'Triggering NIRS and Physio.');
            sendNIRStrg_(app,value);
%             sendPhysioTrg_(app,duration);
            app.physioTrgTimerInit(duration);
        end
        % .................................................................
        
        function sendBolusAndPhysioTrg(app,delay)
            writeLog(app,'Triggering bolus: NIRS data, physio, injector.');
            app.bolusAndPhysTrgTimer.UserData.startedAt = datetime;
            app.bolusAndPhysTrgTimer.UserData.delay = delay;
            app.bolusAndPhysTrgTimer.UserData.state = 'prep';
            app.startTimerAt(app.bolusAndPhysTrgTimer, datetime+seconds(delay-.5));
%             sendBolusAndPhysioTrg_(app);
        end
        % .................................................................
        
        function latency = write2com(app,buf)
%             latency = 0;
%             warning('!!! Triggerbox DISABELED !!!')
%             return; % <<<<<<<<<<<<<< DEBUG <<<<<<<<<<<<<<<<<
            latency = 0;
            if app.humTrgBox_used
                try
                    write(app.scomObj_HumTrgBox,buf,'char'); %uint8
                catch ME
                    uialert(app.BolusTriggerCtrlUIFigure, ...
                        {'Failed to write Human/MV trigger box because', ...
                         ME.message, ...
                         sprintf('Select %s if not used!',app.SCOM_DISCONNECT_STR)}, ...
                        'Error using human/MV trigger box');
                    app.writeLog('ERROR: Failed to use human/MV trigger box: %s',ME.message);
                end
            else
                app.writeLog('Human/MV trigger box not connected.');
            end

            if app.injctrTrgBox_used
                app.scomObj_InjctrTrgBox.flush();
                t = tic;
                write(app.scomObj_InjctrTrgBox,buf,'char'); %uint8
                latency = toc(t);
                while app.scomObj_InjctrTrgBox.NumBytesAvailable<1
                    latency = toc(t);
                    if latency > 2
                        errordlg('No receipt received!','Error writing to COM port','modal')
                        app.writeLog('ERROR: No receipt from injector trigger box received within 2 seconds.')
                        break;
                    end
                end
                
                app.writeLog('Latency was %f seconds (Injector TrgBox)',latency);
            else
                app.writeLog('Injector trigger box not connected.');
            end
%             AnzBytes=app.scomObj.NumBytesAvailable;
%             Data=read(app.scomObj,AnzBytes,'char')
        end
        % .................................................................
        
        function writeLog(app,msg,varargin)
            if ~isempty(varargin)
                msg = sprintf(msg,varargin{:});
            end
            fid = fopen(app.logFile,'a');
            fprintf(fid,'%s: %s\n',datestr(now,'yy-mm-dd HH:MM:SS.FFF'), msg);
            fclose(fid);
        end
        % .................................................................
                
        function [s,po]=createScomObj(app,drpDwn)
            switch drpDwn
                case app.HumanMetaVisionTriggerBoxDropDown
                    propNam = 'scomObj_HumTrgBox';
                    param = app.SerialPortParam;
                case app.InjectorTriggerBoxDropDown
                    propNam = 'scomObj_InjctrTrgBox';
                    param = app.SerialPortParam;
                otherwise, error('BUG!');
            end
            portName = drpDwn.Value;

            if isempty(portName)
                s=1; po=[];
            elseif contains(portName,app.SCOM_DISCONNECT_STR)
                s=1; po=[];
            else
                if ~isempty(app.(propNam)) && isprop(app.(propNam),'Port') && ...
                        strcmp(app.(propNam).Port, app.getMatlabCOMname(portName))
                    % delete obj if obj exists and port name is the same
                    app.(propNam) = [];
                end
                [s,ME,po] = app.testSerialPort(portName);
                if s~=0
                    warndlg({sprintf('Failed to create serial port object with COM port %s! Check/reestablish connection!',portName), ME.message}, ...
                        'Error using COM port');
                end
            end
            app.(propNam) = po;
            if ~isempty(app.(propNam))
                if ~isempty(regexp(portName,app.PreferedCOMnamePatt,'once'))
                    drpDwn.BackgroundColor = [0.4980    0.9412    0.3843];
                else
                    drpDwn.BackgroundColor = [0.9490    0.9255    0.2667];
                end
            elseif contains(portName,app.SCOM_DISCONNECT_STR)
                drpDwn.BackgroundColor = [0.94,0.94,0.94];
            else
                drpDwn.BackgroundColor = [0.8672    0.5273    0.3828];
            end
        end
        % .................................................................

        function [s,ME,po] = testSerialPort(app,name)
            comName = app.getMatlabCOMname(name);
            ME = []; po = [];
            s=0;
%             try delete(app.SerialComObj); end
            try
                po = serialport(comName,app.SerialPortParam{:});
                if nargout<3, delete(po); end
            catch ME
                s=1;
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function init_gui(app)
            appPth = fileparts(mfilename('fullpath'));
            % ---- SET UP LSL OUTLET ---------------------------------
            if isdeployed
                lslPth = fileparts(fileparts(appPth));
                disp(['loading lsl_lib from ' lslPth])
%                 f = dir(fullfile(filepath,'liblsl-Matlab','bin'));
%                 char({f.name})
                lslib = lsl_loadlib_(fullfile(lslPth,'liblsl-Matlab','bin','lsl.dll'));
            else
                lslPth = fullfile(appPth,'liblsl-Matlab');
                addpath(genpath(lslPth));
                if ~exist('lsl_loadlib','file')
                    d = uigetdir(userpath,'Select liblsl-Matlab directory');
                    addpath(genpath(d));
                end
                lslib = lsl_loadlib();
            end
            OutTInfo = lsl_streaminfo(lslib, app.OutTName, app.OutTLabel, 1, 0, 'cf_int8', 'sdfwerr32432');
            app.lsloutlet = lsl_outlet(OutTInfo);
            if ~0, disp(['LSL outlet ''' app.OutTName ''' created.']); end

            
            % ------ SET UP COM PORT ---------------------------------
            app.updateCOMportDropDown(app.getCOMports);
            % app.createScomObj();


            % ------ SET UP LogFile ---------------------------------
            if ~exist(app.logPath,'dir'), mkdir(app.logPath); end
            app.logFile = fullfile(app.logPath,sprintf('%s.txt',datestr(now,'yyyymmdd')));
            
            
            % ------ SET UP TimerObjects ------------------------------
            app.physioTrgTimer = timer(...
                "BusyMode",                "queue",...
                "Name",                    'physioTriggerTimer', ...
                "ExecutionMode",           "singleShot", ...
                ..."StartFcn",                @(x,y)app.physioTrgTimerInit(x,y),...
                "TimerFcn",                @(x,y)app.physioTrgTimerFnc(x,y), ...
                "UserData",                struct('COMlatency',[],'duration',[],'startedAt',[]));
            app.bolusAndPhysTrgTimer = timer(...
                "BusyMode",                "queue",...
                "Name",                    'physioTriggerTimer', ...
                "ExecutionMode",           "singleShot", ...
                "TimerFcn",                @(x,y)app.bolusAndPhysTrgTimerFnc(x,y), ...
                "StopFcn",                 @(x,y)app.bolusAndPhysTrgTimerStopFnc(x,y), ...
                "UserData",                struct('COMlatency',[],'delay',[],'startedAt',[],'state',[]));
            

        end

        % Button pushed function: BOLUSButton
        function bolus_init(app, event)
            answer = inputdlg({'Delay in seconds:'},'Trigger BOLUS',[1,35],{'0'});
            if isempty(answer)
                return;
            else
                tdelay = str2double(answer{1});
                if ~isempty(tdelay) && isfinite(tdelay)
                    fprintf('%s (TCG): Waiting for %d seconds before bolus init...\n', ...
                        datestr(now,'yyyy-mm-dd HH:MM:SS'), tdelay(1));
%                     pause(tdelay(1));
                else
                    warndlg('Failed to interpret entered delay! Bolus wont be initiated.');
                    return;
                end
            end
            sendBolusAndPhysioTrg(app,tdelay);
        end

        % Button pushed function: sendcustomtriggerButton
        function sendCustomTrg(app, event)
            nirsTrgNum = app.TrgNumEditField.Value;
            physTrgDur = app.TrgDurationEditField.Value;
            if nirsTrgNum>0 && physTrgDur>0
                msg = sprintf('Sending trigger %d to NIRS and setting Physio-Trigger to high for %.1f seconds.',nirsTrgNum,physTrgDur);
                fncHndl = @()app.sendNIRSandPhysioTrg(nirsTrgNum,physTrgDur);
            elseif nirsTrgNum>0
                msg = sprintf('Sending trigger %d to NIRS.',nirsTrgNum);
                fncHndl = @()app.sendNIRStrg(nirsTrgNum);
            elseif physTrgDur>0
                msg = sprintf('Setting Physio-Trigger to high for %.1f seconds.',physTrgDur);
                fncHndl = @()app.sendPhysioTrg(physTrgDur);
            else
                msgbox('No trigger to send.');
                return;
            end
            answer = uiconfirm(app.BolusTriggerCtrlUIFigure, ...
                msg, 'Confirm custom trigger');
            if strcmpi(answer,'ok')
                fncHndl();
            end
        end

        % Close request function: BolusTriggerCtrlUIFigure
        function figDestructor(app, event)
%             fclose(app.logfid)
            try app.SerialComObj.delete(); end
            delete(app)

        end

        % Drop down opening function: HumanMetaVisionTriggerBoxDropDown, 
        % ...and 1 other component
        function RefreshTrgportsDropdownList(app, event)
            app.updateCOMportDropDown(app.getCOMports);
            % app.createScomObj();
        end

        % Value changed function: HumanMetaVisionTriggerBoxDropDown, 
        % ...and 1 other component
        function SerialPortDropDownValueChanged(app, event)
            app.createScomObj(event.Source);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.BolusTriggerCtrlUIFigure = uifigure('Visible', 'off');
            app.BolusTriggerCtrlUIFigure.Name = 'BolusTriggerCtrl';
            app.BolusTriggerCtrlUIFigure.CloseRequestFcn = createCallbackFcn(app, @figDestructor, true);
            app.GridLayout = uigridlayout(app.BolusTriggerCtrlUIFigure);
            app.BOLUSButton = uibutton(app.GridLayout, 'push');
            app.BOLUSButton.ButtonPushedFcn = createCallbackFcn(app, @bolus_init, true);
            app.BOLUSButton.Text = 'BOLUS';
            app.sendcustomtriggerButton = uibutton(app.GridLayout, 'push');
            app.sendcustomtriggerButton.ButtonPushedFcn = createCallbackFcn(app, @sendCustomTrg, true);
            app.sendcustomtriggerButton.Text = 'send custom trigger:';
            app.NumberAuroraLabel = uilabel(app.GridLayout);
            app.NumberAuroraLabel.Text = 'Number (Aurora):';
            app.DurationPhysioMonitorLabel = uilabel(app.GridLayout);
            app.DurationPhysioMonitorLabel.Text = 'Duration (PhysioMonitor):';
            app.Label = uilabel(app.GridLayout);
            app.Label.Text = '[1-255]';
            app.TrgNumEditField = uieditfield(app.GridLayout, 'numeric');
            app.secondsEditFieldLabel = uilabel(app.GridLayout);
            app.secondsEditFieldLabel.Text = ' seconds';
            app.TrgDurationEditField = uieditfield(app.GridLayout, 'numeric');
            app.InjectorTriggerBoxDropDownLabel = uilabel(app.GridLayout);
            app.InjectorTriggerBoxDropDownLabel.Text = 'Injector Trigger-Box:';
            app.InjectorTriggerBoxDropDown = uidropdown(app.GridLayout);
            app.InjectorTriggerBoxDropDown.Items = {};
            app.InjectorTriggerBoxDropDown.DropDownOpeningFcn = createCallbackFcn(app, @RefreshTrgportsDropdownList, true);
            app.InjectorTriggerBoxDropDown.ValueChangedFcn = createCallbackFcn(app, @SerialPortDropDownValueChanged, true);
            app.InjectorTriggerBoxDropDown.Tooltip = {'Choose COM port to which TriggerBox is connected to. See Device Manager (Geräte Manager), name should be ''Serielles USB-Geraet'''};
            app.InjectorTriggerBoxDropDown.Value = {};
            app.HumanMetaVisionTriggerBoxLabel = uilabel(app.GridLayout);
            app.HumanMetaVisionTriggerBoxLabel.Text = 'Human/MetaVision Trigger-Box:';
            app.HumanMetaVisionTriggerBoxDropDown = uidropdown(app.GridLayout);
            app.HumanMetaVisionTriggerBoxDropDown.Items = {};
            app.HumanMetaVisionTriggerBoxDropDown.DropDownOpeningFcn = createCallbackFcn(app, @RefreshTrgportsDropdownList, true);
            app.HumanMetaVisionTriggerBoxDropDown.ValueChangedFcn = createCallbackFcn(app, @SerialPortDropDownValueChanged, true);
            app.HumanMetaVisionTriggerBoxDropDown.Value = {};
            % Show the figure after all components are created
            app.BolusTriggerCtrlUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TrigCtrlGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.BolusTriggerCtrlUIFigure)

            % Execute the startup function
            runStartupFcn(app, @init_gui)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.BolusTriggerCtrlUIFigure)
        end
    end
end